classdef encryption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        InputTextArea       matlab.ui.control.TextArea
        InputTextAreaLabel  matlab.ui.control.Label
        EncryptButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            main;
            delete(app)
        end

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            text = app.InputTextArea.Value{1};
            [c, k] = encrypt(text);
            save("cipher.mat","c","k");
            key = '';
            for i = 1 : size(k, 1)
                for j = 1 : size(k, 2)
                    key = [key sprintf('%d',k(i,j))];
                    key = [key ' '];
                end
                key = [key newline];
            end
            
            msgbox(sprintf('Cipher: %s%cKey:%c%s', c, newline, newline, key),'Fault Detected','warn')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EncryptButton = uibutton(app.UIFigure, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.InputTextAreaLabel = uilabel(app.UIFigure);
            app.InputTextAreaLabel.Text = 'Input';
            app.InputTextArea = uitextarea(app.UIFigure);
            app.InputTextArea.Placeholder = 'text to be encrypted';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = encryption

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end